classdef adg_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        PlikMenu                  matlab.ui.container.Menu
        EksportujMenu             matlab.ui.container.Menu
        WczytajMenu               matlab.ui.container.Menu
        ZapiszMenu                matlab.ui.container.Menu
        UsuPrbkiMenu              matlab.ui.container.Menu
        ZamknijMenu               matlab.ui.container.Menu
        WywietlanieMenu           matlab.ui.container.Menu
        SiatkaMenu                matlab.ui.container.Menu
        MiniSiatkaMenu            matlab.ui.container.Menu
        LegendaMenu               matlab.ui.container.Menu
        SzczytyMenu               matlab.ui.container.Menu
        TytuMenu                  matlab.ui.container.Menu
        PomocMenu                 matlab.ui.container.Menu
        JakzaczMenu               matlab.ui.container.Menu
        PorwnanieokienMenu        matlab.ui.container.Menu
        WskazwkidoboruokienMenu   matlab.ui.container.Menu
        KalkulatorsprytnyMenu     matlab.ui.container.Menu
        InformacjeoaplikacjiMenu  matlab.ui.container.Menu
        GridLayout3               matlab.ui.container.GridLayout
        TabGroup2                 matlab.ui.container.TabGroup
        RodzajeWykreswTab         matlab.ui.container.Tab
        GridLayout5               matlab.ui.container.GridLayout
        ListBoxWykres             matlab.ui.control.ListBox
        ParametryokienparametrycznychPanel  matlab.ui.container.Panel
        GridLayout                matlab.ui.container.GridLayout
        Taylor2EditField          matlab.ui.control.NumericEditField
        Taylor2EditFieldLabel     matlab.ui.control.Label
        Taylor1EditField          matlab.ui.control.NumericEditField
        Taylor1EditFieldLabel     matlab.ui.control.Label
        SincEditField             matlab.ui.control.NumericEditField
        SincEditFieldLabel        matlab.ui.control.Label
        ChebyEditField            matlab.ui.control.NumericEditField
        ChebyEditFieldLabel       matlab.ui.control.Label
        TukeyEditField            matlab.ui.control.NumericEditField
        TukeyEditFieldLabel       matlab.ui.control.Label
        GaussEditField            matlab.ui.control.NumericEditField
        GaussLabel                matlab.ui.control.Label
        KaiserEditField           matlab.ui.control.NumericEditField
        KaiserEditFieldLabel      matlab.ui.control.Label
        TabGroup                  matlab.ui.container.TabGroup
        OknaTab                   matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        ListBoxOkno               matlab.ui.control.ListBox
        ZeropadTab                matlab.ui.container.Tab
        GridLayout4               matlab.ui.container.GridLayout
        ListBoxZeroPad            matlab.ui.control.ListBox
        UyjdyskretnegouzupenieniazeramiCheckBox  matlab.ui.control.CheckBox
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        N               % Liczba probek
        n               % indeks probek
        x               % wektor probek sygnalu
        w               % wektor probek okna czasowego
        f               % wektor próbek fft
        modf            % moduł widma fft z/bez skali logarytmicznej
        Nrokna          % numer wybranego okna czasowego
        Nrwykresu       % numer wybranego wykresu

        pKaiser         %parametr okna kaisera
        pGauss          %parametr okna gaussa
        pTukey          %parametr okna Tukeya
        pCheby          %parametr okna Chebysheva
        pTaylor1        %parametr okna Taylora
        pTaylor2        %parametr okna Taylora
        pSinc

        padEn           % wskażnik użycia metody zero padding (0=off, 1=on)
        padNum          % liczba zer użyta w metodzie zero padding fft

        minorGridOn 
    end
    
    methods (Access = private)
        
        function RysujWykres1(app)
            % Metoda rysująca wykres w dziedzinie czasu

            % Tworzę od nowa wektor próbek bo nie biorę próbek uzupełnienia
            % zerami z kolejnych wykresów
            app.n = 0:app.N-1; 

            set(app.UIAxes,'YLimMode',"auto");

            plot(app.UIAxes, app.n,app.x,'- .',"LineWidth", 1.5,"MarkerSize",10,"DisplayName","Sygnał");
            hold (app.UIAxes, "on");
            plot(app.UIAxes,app.n,app.w,'- .',"LineWidth", 1.5,"MarkerSize",10,"DisplayName","Okno");
            plot(app.UIAxes,app.n,app.w.*app.x,'- .',"LineWidth", 1.5,"MarkerSize",10,"DisplayName","Sygnał x Okno");
            hold (app.UIAxes, "off");

            kolory_linii = ["#0072BD","#77AC30","#A2142F"];
            colororder(app.UIAxes, kolory_linii);

            xlim(app.UIAxes, [0,app.N]);
            ylim(app.UIAxes, "auto");
            title(app.UIAxes, "Przebieg czasowy sygnału, okno i zokienkowany przebieg");
            xlabel(app.UIAxes, "Nr. próbki");
            ylabel(app.UIAxes, "Amplituda");
        end
        
        function RysujWykres2(app)
            % Metoda rysująca wykres wyniku fft w skali liniowej

            app.f=fft(app.x.*app.w, not(app.padEn)*app.N + app.padEn*app.padNum);
            df = app.N/length(app.f);
            app.n = 0:df:app.N-df; 

            app.modf=abs(app.f)/(app.N/2);
            set(app.UIAxes,'YLimMode',"auto")

            plot(app.UIAxes, app.n, app.modf,'- .');

            xlim(app.UIAxes, [0,app.N]);
            ylim(app.UIAxes, "auto");

            title(app.UIAxes, "Widmo sygnału w skali liniowej");
            xlabel(app.UIAxes, "Nr. próbki");
            ylabel(app.UIAxes, "Moc prążka");
        end

        function RysujWykres3(app)
            % Metoda rysująca wyniki fft przeskalowane na skalę
            % logarytmiczną

            app.f=fft(app.x.*app.w, not(app.padEn)*app.N + app.padEn*app.padNum);
            df = app.N/length(app.f);
            app.n = 0:df:app.N-df; 

            app.modf=20*log10(abs(app.f)/(app.N/2));
            set(app.UIAxes,'YLimMode',"auto")

            plot(app.UIAxes, app.n,app.modf,'- .',"Color","#77AC30","LineWidth", 1);

            xlim(app.UIAxes, [0,app.N]);
            ylim(app.UIAxes, "auto");

            title(app.UIAxes, "Widmo sygnału w skali logarytmicznej");
            xlabel(app.UIAxes, "Nr. próbki");
            ylabel(app.UIAxes, "Moc prążka");
        end

        function RysujWykres4(app)
            % Metoda teoretycznie rysująca fazę ale nikt nie wie na pewno
            % bo faza jest dziwna :p

            app.f=fft(app.x.*app.w, not(app.padEn)*app.N + app.padEn*app.padNum);
            df = app.N/length(app.f);
            app.n = 0:df:app.N-df;  

            app.modf=abs(app.f)/(app.N/2);
            alfa = angle(app.f);
            idx = app.modf<0.00001;
            alfa(idx) = NaN;

            set(app.UIAxes,'YLimMode',"auto")
            plot(app.UIAxes, app.n, alfa ,'- .',"Color","#A2142F","LineWidth", 1);

            xlim(app.UIAxes, [0,app.N]);
            ylim(app.UIAxes, "auto");


            title(app.UIAxes, "Faza sygnału");
            xlabel(app.UIAxes, "Nr. próbki");
            ylabel(app.UIAxes, "Kąt");
        end
        
        function RysujWykres(app)
            % Wybór pokazywanego wykresu

            clear f;    % Próba naprawy błędu przepełnienia pamięci przy zmianach ilości zer, niewiadomo czy skuteczna 
            switch(app.Nrwykresu)
                case 1
                    RysujWykres1(app);
                case 2
                    RysujWykres2(app);
                case 3
                    RysujWykres3(app);
                case 4
                    RysujWykres4(app);
            end
        end
        
        function ObliczOkno(app)
            % Metoda licząca okna, duh

            app.KaiserEditField.Enable = "off";
            app.GaussEditField.Enable = "off";
            app.TukeyEditField.Enable = "off";
            app.ChebyEditField.Enable = "off";
            app.SincEditField.Enable = "off";
            app.Taylor1EditField.Enable = "off";
            app.Taylor2EditField.Enable = "off";
            
            switch(app.Nrokna)
                case 1
                    app.w = rectwin(app.N);
                    app.w = app.w';
                case 2
                    app.w = 2.*hann(app.N, "periodic");
                    app.w = app.w';
                case 3
                    app.w = 2.*triang(app.N+1);
                    app.w(length(app.w))=[];
                    app.w = app.w';
                case 4 
                    app.w = 1.8519.*hamming(app.N, "periodic");
                    app.w = app.w';
                case 5
                    app.w = 2.3810.*blackman(app.N, "periodic");
                    app.w = app.w';
                case 6
                    app.KaiserEditField.Enable = "on";
                    app.w = kaiser(app.N+1, app.pKaiser);
                    app.w(length(app.w))=[];
                    normalizujOkno(app);
                case 7
                    app.GaussEditField.Enable = "on";
                    app.w = gausswin(app.N+1, app.pGauss);
                    app.w(length(app.w))=[];
                    normalizujOkno(app);
                case 8
                    app.TukeyEditField.Enable = "on";
                    app.w = tukeywin(app.N+1, app.pTukey);
                    app.w(length(app.w))=[];
                    normalizujOkno(app);
                case 9
                    app.ChebyEditField.Enable = "on";
                    app.w = chebwin(app.N+1, app.pCheby);
                    app.w(length(app.w))=[];
                    normalizujOkno(app);
                case 10
                    app.w = blackmanharris(app.N, 'periodic');
                    normalizujOkno(app);
                case 11
                    app.w = nuttallwin(app.N, 'periodic');
                    normalizujOkno(app);
                case 12
                    app.SincEditField.Enable = "on";
                    dv = app.pSinc*2/app.N;
                    vec = -1*app.pSinc:dv:1*app.pSinc;

                    app.w = sinc(vec).*(hann(length(vec)))';
                    app.w = app.w';
                    app.w(length(app.w))=[];
                    normalizujOkno(app);
                case 13
                    app.w = barthannwin(app.N+1);
                    app.w(length(app.w))=[];
                    normalizujOkno(app);
                case 14
                    app.w = bartlett(app.N+1);
                    app.w(length(app.w))=[];
                    normalizujOkno(app);
                case 15
                    app.w = bohmanwin(app.N+1);
                    app.w(length(app.w))=[];
                    normalizujOkno(app);
                case 16
                    app.w = flattopwin(app.N+1);
                    app.w(length(app.w))=[];
                    normalizujOkno(app);
                case 17
                    app.w = parzenwin(app.N+1);
                    app.w(length(app.w))=[];
                    normalizujOkno(app);
                case 18
                    app.Taylor1EditField.Enable = "on";
                    app.Taylor2EditField.Enable = "on";
                    app.w = taylorwin(app.N+1, app.pTaylor1, app.pTaylor2);
                    app.w(length(app.w))=[];
                    normalizujOkno(app);
            end
        end


        function normalizujOkno(app)
            % Metoda "normalizacji" okien: 
            % Oblicza sumę wszystkich próbek okna, porównuje z sumą dla
            % okna prostokątnego i skaluje próbki tak żeby ich suma była
            % równa jak dla prostokątnego. Dlaczego? Dużo funkcji w matlab
            % generuje okna o max amplitudzie 1 a to oznacza tłumienie
            % sygnału.

            app.w = app.w';
            sum = 0;
            for i = 1:length(app.w)
                sum = sum + app.w(i);
            end
            
            multiplier = app.N/sum;
            app.w = multiplier.*app.w;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ad_init(app)
            % Inicjalizacja zmiennych wartościami początkowymi

            app.N=128;
            app.n=0:app.N-1;
            app.x=zeros(1,app.N);
            app.w=ones(1,app.N);
            app.Nrokna=1;
            app.Nrwykresu=1;
            RysujWykres1(app);

            app.KaiserEditField.Enable = "off";
            app.GaussEditField.Enable = "off";
            app.TukeyEditField.Enable = "off";
            app.ChebyEditField.Enable = "off";
            app.pKaiser = 0.5;
            app.pGauss = 2.5;
            app.pTukey = 0.5;
            app.pCheby = 100;
            app.pTaylor1 = 4;
            app.pTaylor2 = -30;
            app.pSinc = 4;

            app.padEn = 0;
            app.padNum = app.N;

            app.minorGridOn = false; 
        end

        % Value changed function: ListBoxOkno
        function ad_wybor_okna(app, event)
            app.Nrokna = app.ListBoxOkno.Value;
            ObliczOkno(app);
            RysujWykres(app);
        end

        % Value changed function: ListBoxWykres
        function ad_wybor_wykresu(app, event)
            app.Nrwykresu = app.ListBoxWykres.Value;
            RysujWykres(app);
        end

        % Value changed function: KaiserEditField
        function KaiserEditFieldValueChanged(app, event)
            app.pKaiser = app.KaiserEditField.Value;
            ObliczOkno(app);
            RysujWykres(app);
        end

        % Value changed function: GaussEditField
        function GaussEditFieldValueChanged(app, event)
            app.pGauss = app.GaussEditField.Value;
            ObliczOkno(app);
            RysujWykres(app);            
        end

        % Value changed function: TukeyEditField
        function TukeyEditFieldValueChanged(app, event)
            app.pTukey = app.TukeyEditField.Value;
            ObliczOkno(app);
            RysujWykres(app);  
        end

        % Value changed function: ChebyEditField
        function ChebyEditFieldValueChanged(app, event)
            app.pCheby = app.ChebyEditField.Value;
            ObliczOkno(app);
            RysujWykres(app);
        end

        % Value changed function: ListBoxZeroPad
        function ListBoxZeroPadValueChanged(app, event)
            app.padNum = app.ListBoxZeroPad.Value;

            ObliczOkno(app);
            RysujWykres(app);
        end

        % Value changed function: UyjdyskretnegouzupenieniazeramiCheckBox
        function UyjdyskretnegouzupenieniazeramiCheckBoxValueChanged(app, event)
            app.padEn = app.UyjdyskretnegouzupenieniazeramiCheckBox.Value;
            
            ObliczOkno(app);
            RysujWykres(app);
        end

        % Menu selected function: WczytajMenu
        function WczytajMenuSelected(app, event)
            % Metoda od prowadzącego, pewnie kopia 1:1 z internetu. Pozwala
            % na wczytanie ciągu próbek do analizy
            
            [st1,st2]=uigetfile('*.mat','Czytaj dane');
            if st1~=0
                eval(['load ''' [st2,st1] ''' -mat x']);
                app.x=x;
            end

            % Focus the running singleton app
            runningApp = getRunningApp(app);
            figure(runningApp.UIFigure)
            app = runningApp;

            % Zamiana wektorów kolumnowych na wierszowe 
            if (size(app.x,1)>size(app.x,2))
                app.x = app.x';
            end

            % Próba umożliwienia analizy sygnałów o różnej długości, nie
            % wypróbowana
            app.N = (length(app.x));

            RysujWykres(app);
        end

        % Menu selected function: ZapiszMenu
        function ZapiszMenuSelected(app, event)
            % Metoda "pożyczona" z internetu, służy do zapisywania
            % wykresów pokazanych na plocie w wielu fajnych formatach
            
            filter = ["*.jpg";"*.png";"*.tif";"*.pdf";"*.eps"];
            [filename,filepath] = uiputfile(filter);
            if ischar(filename)
                exportgraphics(app.UIAxes,[filepath filename]);
            end
        end

        % Menu selected function: UsuPrbkiMenu
        function UsuPrbkiMenuSelected(app, event)
            % Metoda usuwająca wczytane próbki a tak naprawdę zastępująca
            % je ciągiem zer
            
            app.N=128;
            app.n=0:app.N-1;
            app.x=zeros(1,app.N);
            app.w=ones(1,app.N);
            app.Nrokna=1;
            app.Nrwykresu=1;
            RysujWykres1(app);
        end

        % Menu selected function: PorwnanieokienMenu
        function PorwnanieokienMenuSelected(app, event)
            % Metoda otwierająca aplikację pomocy z porównaniem wykresów
            winHelpWin(app, app.N, app.pKaiser, app.pGauss, app.pTukey, app.pCheby, app.pTaylor1, app.pTaylor2, app.pSinc);
        end

        % Menu selected function: JakzaczMenu
        function JakzaczMenuSelected(app, event)
            % Metoda pokazująca pliczek pomocy "Jak zacząć"
            
            txt = readlines('help_how2start.txt');
            msgbox(txt,"How 2 start","help");
        end

        % Menu selected function: InformacjeoaplikacjiMenu
        function InformacjeoaplikacjiMenuSelected(app, event)
            txt = readlines('help_appInfo.txt');
            msgbox(txt,"App Info","help");
        end

        % Menu selected function: SiatkaMenu
        function SiatkaMenuSelected(app, event)
            grid(app.UIAxes);
        end

        % Menu selected function: LegendaMenu
        function LegendaMenuSelected(app, event)
            legend(app.UIAxes,"toggle");
        end

        % Menu selected function: SzczytyMenu
        function SzczytyMenuSelected(app, event)
            if (app.Nrwykresu == 1 || app.Nrwykresu == 4)
                txt = "Funkcja dostępna tylko w Ch-kach amplitudowych";
                msgbox(txt,"Error","error");
            end
            if (app.Nrwykresu == 2 || app.Nrwykresu == 3)

                [max_a, max_f] = findpeaks([0,app.modf,0], [-app.n(2),app.n,app.N]);
    
                hold(app.UIAxes,'on');
                plot(app.UIAxes, max_f,max_a,'bo',"MarkerSize",20);
                hold(app.UIAxes,'off');
            end
        end

        % Menu selected function: TytuMenu
        function TytuMenuSelected(app, event)
            title(app.UIAxes, inputdlg("Wpisz nową nazwę wykresu: "));
        end

        % Callback function: not associated with a component
        function Taylor1EditFieldValueChanged(app, event)
            app.pTaylor1 = app.Taylor1EditField.Value;
            ObliczOkno(app);
            RysujWykres(app);
        end

        % Callback function: not associated with a component
        function Taylor2EditFieldValueChanged(app, event)
            app.pTaylor2 = app.Taylor2EditField.Value;
            ObliczOkno(app);
            RysujWykres(app);
        end

        % Value changed function: SincEditField
        function SincEditFieldValueChanged(app, event)
            app.pSinc = app.SincEditField.Value;
            ObliczOkno(app);
            RysujWykres(app);
        end

        % Value changed function: Taylor1EditField
        function Taylor1EditFieldValueChanged2(app, event)
            app.pTaylor1 = app.Taylor1EditField.Value;
            ObliczOkno(app);
            RysujWykres(app);
        end

        % Value changed function: Taylor2EditField
        function Taylor2EditFieldValueChanged2(app, event)
            app.pTaylor2 = app.Taylor2EditField.Value;
            ObliczOkno(app);
            RysujWykres(app);
        end

        % Menu selected function: WskazwkidoboruokienMenu
        function WskazwkidoboruokienMenuSelected(app, event)
            txt = readlines('help_tips&tricks.txt');
            msgbox(txt,"App Info","help");
        end

        % Menu selected function: MiniSiatkaMenu
        function MiniSiatkaMenuSelected(app, event)
            app.minorGridOn = ~app.minorGridOn;

            if app.minorGridOn
                app.UIAxes.XMinorGrid = 'on';
                app.UIAxes.YMinorGrid = 'on';
                app.UIAxes.XMinorTick = 'on';
                app.UIAxes.YMinorTick = 'on';
            else
                app.UIAxes.XMinorGrid = 'off';
                app.UIAxes.YMinorGrid = 'off';
                app.UIAxes.XMinorTick = 'off';
                app.UIAxes.YMinorTick = 'off';
            end
        end

        % Menu selected function: KalkulatorsprytnyMenu
        function KalkulatorsprytnyMenuSelected(app, event)
            kalkulator(app, app.N);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlikMenu = uimenu(app.UIFigure);
            app.PlikMenu.Text = '  Plik  ';
            app.EksportujMenu = uimenu(app.PlikMenu);
            app.EksportujMenu.Separator = 'on';
            app.EksportujMenu.Text = 'Eksportuj...';
            app.WczytajMenu = uimenu(app.PlikMenu);
            app.WczytajMenu.MenuSelectedFcn = createCallbackFcn(app, @WczytajMenuSelected, true);
            app.WczytajMenu.Separator = 'on';
            app.WczytajMenu.Accelerator = 'd';
            app.WczytajMenu.Text = 'Wczytaj...';
            app.ZapiszMenu = uimenu(app.PlikMenu);
            app.ZapiszMenu.MenuSelectedFcn = createCallbackFcn(app, @ZapiszMenuSelected, true);
            app.ZapiszMenu.Accelerator = 's';
            app.ZapiszMenu.Text = 'Zapisz...';
            app.UsuPrbkiMenu = uimenu(app.PlikMenu);
            app.UsuPrbkiMenu.MenuSelectedFcn = createCallbackFcn(app, @UsuPrbkiMenuSelected, true);
            app.UsuPrbkiMenu.Separator = 'on';
            app.UsuPrbkiMenu.Text = 'Usuń Próbki';
            app.ZamknijMenu = uimenu(app.PlikMenu);
            app.ZamknijMenu.Text = 'Zamknij';
            app.WywietlanieMenu = uimenu(app.UIFigure);
            app.WywietlanieMenu.Text = ' Wyświetlanie ';
            app.SiatkaMenu = uimenu(app.WywietlanieMenu);
            app.SiatkaMenu.MenuSelectedFcn = createCallbackFcn(app, @SiatkaMenuSelected, true);
            app.SiatkaMenu.Text = 'Siatka';
            app.MiniSiatkaMenu = uimenu(app.WywietlanieMenu);
            app.MiniSiatkaMenu.MenuSelectedFcn = createCallbackFcn(app, @MiniSiatkaMenuSelected, true);
            app.MiniSiatkaMenu.Text = 'Mini Siatka';
            app.LegendaMenu = uimenu(app.WywietlanieMenu);
            app.LegendaMenu.MenuSelectedFcn = createCallbackFcn(app, @LegendaMenuSelected, true);
            app.LegendaMenu.Text = 'Legenda';
            app.SzczytyMenu = uimenu(app.WywietlanieMenu);
            app.SzczytyMenu.MenuSelectedFcn = createCallbackFcn(app, @SzczytyMenuSelected, true);
            app.SzczytyMenu.Text = 'Szczyty';
            app.TytuMenu = uimenu(app.WywietlanieMenu);
            app.TytuMenu.MenuSelectedFcn = createCallbackFcn(app, @TytuMenuSelected, true);
            app.TytuMenu.Separator = 'on';
            app.TytuMenu.Text = 'Tytuł...';
            app.PomocMenu = uimenu(app.UIFigure);
            app.PomocMenu.Text = '  Pomoc  ';
            app.JakzaczMenu = uimenu(app.PomocMenu);
            app.JakzaczMenu.MenuSelectedFcn = createCallbackFcn(app, @JakzaczMenuSelected, true);
            app.JakzaczMenu.Text = 'Jak zacząć?';
            app.PorwnanieokienMenu = uimenu(app.PomocMenu);
            app.PorwnanieokienMenu.MenuSelectedFcn = createCallbackFcn(app, @PorwnanieokienMenuSelected, true);
            app.PorwnanieokienMenu.Accelerator = 'h';
            app.PorwnanieokienMenu.Text = 'Porównanie okien';
            app.WskazwkidoboruokienMenu = uimenu(app.PomocMenu);
            app.WskazwkidoboruokienMenu.MenuSelectedFcn = createCallbackFcn(app, @WskazwkidoboruokienMenuSelected, true);
            app.WskazwkidoboruokienMenu.Text = 'Wskazówki doboru okien';
            app.KalkulatorsprytnyMenu = uimenu(app.PomocMenu);
            app.KalkulatorsprytnyMenu.MenuSelectedFcn = createCallbackFcn(app, @KalkulatorsprytnyMenuSelected, true);
            app.KalkulatorsprytnyMenu.Accelerator = 'K';
            app.KalkulatorsprytnyMenu.Text = 'Kalkulator sprytny';
            app.InformacjeoaplikacjiMenu = uimenu(app.PomocMenu);
            app.InformacjeoaplikacjiMenu.MenuSelectedFcn = createCallbackFcn(app, @InformacjeoaplikacjiMenuSelected, true);
            app.InformacjeoaplikacjiMenu.Text = 'Informacje o aplikacji';
            app.GridLayout3 = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout3);
            app.OknaTab = uitab(app.TabGroup);
            app.OknaTab.Title = 'Okna';
            app.GridLayout2 = uigridlayout(app.OknaTab);
            app.ListBoxOkno = uilistbox(app.GridLayout2);
            app.ListBoxOkno.Items = {'Okno prostokątne', 'Okon Hanna', 'Okon trójkątne', 'Okno Hamminga', 'Okno Blackmana', '[p]Okno Kaisera', '[p]Okno Gaussa', '[p]Okno Tukeya', '[p]Okno Chebysheva', 'Okno Blackmana-Harrisa', 'Okno Nutalla', '[p]Okno Sinc', 'Okno Bartletta-Hanna', 'Okno Bartletta', 'Okno Bohmana', 'Okno Flattop', 'Okno Parzena', '[p]Okno Taylora'};
            app.ListBoxOkno.ItemsData = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18];
            app.ListBoxOkno.ValueChangedFcn = createCallbackFcn(app, @ad_wybor_okna, true);
            app.ListBoxOkno.Value = 1;
            app.ZeropadTab = uitab(app.TabGroup);
            app.ZeropadTab.Title = 'Zero pad';
            app.GridLayout4 = uigridlayout(app.ZeropadTab);
            app.UyjdyskretnegouzupenieniazeramiCheckBox = uicheckbox(app.GridLayout4);
            app.UyjdyskretnegouzupenieniazeramiCheckBox.ValueChangedFcn = createCallbackFcn(app, @UyjdyskretnegouzupenieniazeramiCheckBoxValueChanged, true);
            app.UyjdyskretnegouzupenieniazeramiCheckBox.Text = 'Użyj dyskretnego uzupełnienia zerami';
            app.ListBoxZeroPad = uilistbox(app.GridLayout4);
            app.ListBoxZeroPad.Items = {'128', '256', '512', '1024', '2048', '4096', '8192', '16384', '32768', '65536'};
            app.ListBoxZeroPad.ItemsData = [128 256 512 1024 2048 4096 8192 16384 32768 65536];
            app.ListBoxZeroPad.ValueChangedFcn = createCallbackFcn(app, @ListBoxZeroPadValueChanged, true);
            app.ListBoxZeroPad.Value = 128;
            app.ParametryokienparametrycznychPanel = uipanel(app.GridLayout3);
            app.ParametryokienparametrycznychPanel.Title = 'Parametry okien parametrycznych';
            app.GridLayout = uigridlayout(app.ParametryokienparametrycznychPanel);
            app.KaiserEditFieldLabel = uilabel(app.GridLayout);
            app.KaiserEditFieldLabel.Text = 'Kaiser:';
            app.KaiserEditField = uieditfield(app.GridLayout, 'numeric');
            app.KaiserEditField.ValueChangedFcn = createCallbackFcn(app, @KaiserEditFieldValueChanged, true);
            app.KaiserEditField.Value = 0.5;
            app.GaussLabel = uilabel(app.GridLayout);
            app.GaussLabel.Text = 'Gauss:';
            app.GaussEditField = uieditfield(app.GridLayout, 'numeric');
            app.GaussEditField.ValueChangedFcn = createCallbackFcn(app, @GaussEditFieldValueChanged, true);
            app.GaussEditField.Value = 2.5;
            app.TukeyEditFieldLabel = uilabel(app.GridLayout);
            app.TukeyEditFieldLabel.Text = 'Tukey:';
            app.TukeyEditField = uieditfield(app.GridLayout, 'numeric');
            app.TukeyEditField.ValueChangedFcn = createCallbackFcn(app, @TukeyEditFieldValueChanged, true);
            app.TukeyEditField.Value = 0.5;
            app.ChebyEditFieldLabel = uilabel(app.GridLayout);
            app.ChebyEditFieldLabel.Text = 'Cheby:';
            app.ChebyEditField = uieditfield(app.GridLayout, 'numeric');
            app.ChebyEditField.ValueChangedFcn = createCallbackFcn(app, @ChebyEditFieldValueChanged, true);
            app.ChebyEditField.Value = 100;
            app.SincEditFieldLabel = uilabel(app.GridLayout);
            app.SincEditFieldLabel.Text = 'Sinc:';
            app.SincEditField = uieditfield(app.GridLayout, 'numeric');
            app.SincEditField.ValueChangedFcn = createCallbackFcn(app, @SincEditFieldValueChanged, true);
            app.SincEditField.Value = 4;
            app.Taylor1EditFieldLabel = uilabel(app.GridLayout);
            app.Taylor1EditFieldLabel.Text = 'Taylor 1:';
            app.Taylor1EditField = uieditfield(app.GridLayout, 'numeric');
            app.Taylor1EditField.ValueChangedFcn = createCallbackFcn(app, @Taylor1EditFieldValueChanged2, true);
            app.Taylor1EditField.Value = 4;
            app.Taylor2EditFieldLabel = uilabel(app.GridLayout);
            app.Taylor2EditFieldLabel.Text = 'Taylor 2:';
            app.Taylor2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Taylor2EditField.ValueChangedFcn = createCallbackFcn(app, @Taylor2EditFieldValueChanged2, true);
            app.Taylor2EditField.Value = -30;
            app.TabGroup2 = uitabgroup(app.GridLayout3);
            app.RodzajeWykreswTab = uitab(app.TabGroup2);
            app.RodzajeWykreswTab.Title = 'Rodzaje Wykresów';
            app.GridLayout5 = uigridlayout(app.RodzajeWykreswTab);
            app.ListBoxWykres = uilistbox(app.GridLayout5);
            app.ListBoxWykres.Items = {'Wykres w dziedzinie czasu', 'Ch-ka amplitudowa', 'Ch-ka amplitudowa w dB', 'Ch-ka fazowa'};
            app.ListBoxWykres.ItemsData = [1 2 3 4];
            app.ListBoxWykres.ValueChangedFcn = createCallbackFcn(app, @ad_wybor_wykresu, true);
            app.ListBoxWykres.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adg_v2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @ad_init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end