classdef winHelpWin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        PorwnanieokienTab              matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        PozycjawskanikaEditField       matlab.ui.control.NumericEditField
        PozycjawskanikaEditFieldLabel  matlab.ui.control.Label
        PorwnajOknaPanel               matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        OknoTayloraCheckBox            matlab.ui.control.CheckBox
        OknoParzenaCheckBox            matlab.ui.control.CheckBox
        OknoFlattopCheckBox            matlab.ui.control.CheckBox
        OknoBohmannaCheckBox           matlab.ui.control.CheckBox
        OknoBartlettaCheckBox          matlab.ui.control.CheckBox
        OknoBartlettaHannaCheckBox     matlab.ui.control.CheckBox
        OknoSincCheckBox               matlab.ui.control.CheckBox
        OknoNutallaCheckBox            matlab.ui.control.CheckBox
        OknoBlackmanaHarrisaCheckBox   matlab.ui.control.CheckBox
        OknoProstoktneCheckBox         matlab.ui.control.CheckBox
        OknoChebyshevaCheckBox         matlab.ui.control.CheckBox
        OknoTukeyaCheckBox             matlab.ui.control.CheckBox
        OknoGaussaCheckBox             matlab.ui.control.CheckBox
        OknoKaiseraCheckBox            matlab.ui.control.CheckBox
        OknoBlackmanaCheckBox          matlab.ui.control.CheckBox
        OknoHammingaCheckBox           matlab.ui.control.CheckBox
        OknoTrjktneCheckBox            matlab.ui.control.CheckBox
        OknoHannaCheckBox              matlab.ui.control.CheckBox
        WyczwszystkieButton            matlab.ui.control.Button
        WczwszystkieButton             matlab.ui.control.Button
        ParametryokienparametrycznychPanel  matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        SincEditField                  matlab.ui.control.NumericEditField
        SincEditFieldLabel             matlab.ui.control.Label
        Taylor2EditField               matlab.ui.control.NumericEditField
        Taylor2EditFieldLabel          matlab.ui.control.Label
        Taylor1EditField               matlab.ui.control.NumericEditField
        Taylor1EditFieldLabel          matlab.ui.control.Label
        ChebyEditField                 matlab.ui.control.NumericEditField
        ChebyEditFieldLabel            matlab.ui.control.Label
        TukeyEditField                 matlab.ui.control.NumericEditField
        TukeyEditFieldLabel            matlab.ui.control.Label
        GaussEditField                 matlab.ui.control.NumericEditField
        GaussEditFieldLabel            matlab.ui.control.Label
        KaiserEditField                matlab.ui.control.NumericEditField
        KaiserEditFieldLabel           matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        Tabelaparametrwokien           matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        UITable                        matlab.ui.control.Table
        WskazwkidoboruokienTab         matlab.ui.container.Tab
        GridLayout6                    matlab.ui.container.GridLayout
        TextArea_2                     matlab.ui.control.TextArea
    end

    
    properties (Access = private)

        adg_v2          % odnośnik do aplikacji nadrzędnej
        
        n               % wektor próbek sygnału
        N               % ilość próbek sygnału
        padNum          % ilość zer w metodzie zero padding
        pointerPos      % pozycja wskaźnika pionowego

        % parametry okien: 
        pKaiser         % kaisera,
        pGauss          % gaussa
        pTukey          % tuckeya
        pCheby          % Chebysheva
        pTaylor1
        pTaylor2
        pSinc
        
        % wartości próbek okien:
        wrectwin        % prostokątnego
        whann           % hanna
        wtriang         % trójkątnego
        whamming        % hamminga
        wblackman       % blackmana
        wkaiser         % kaisera
        wgausswin       % gaussa
        wtukeywin       % tukeya
        wchebwin        % chebysheva
        wblackmanharris
        wnuttallwin
        wsinc
        wbarthannwin
        wbartlett
        wbohmanwin
        wflattopwin
        wparzenwin
        wtaylorwin
    end
    
    methods (Access = private)
        function wynik = normalizujOkno(app, okno)
            % Metoda "normalizacji" okien, sprawia że pole pod wykresem
            % każdego okna jest równa polu okna prostokątnego
            sum = 0;
            for i = 1:length(okno)
                sum = sum + okno(i);
            end
            
            multiplier = app.N/sum;
            wynik = multiplier.*okno;
        end
        
        function aktualizujOkno(app)
            % Metoda służąca do wyświetlenia okien odznaczonych checkboxami
            % Sprawdza wartość zmiennej value każdego checkboxa i jeżeli
            % jest 1 wyświetla dane okno
            
            % Pusty plot pozwala na poprawne działanie hold on, bez niego
            % nakładają się kolejne wykresy na siebie w nieskończoność,
            % dodatkowo służy za opis dla legendy :)
            plot(app.UIAxes,0,0,"w","DisplayName","Nazwa okna:");

            hold(app.UIAxes, "on");
            xline(app.UIAxes, app.pointerPos, 'r', "DisplayName", "Wskaźnik", 'LineWidth', 0.1);

            if(app.OknoProstoktneCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wrectwin, 65536)/(app.N))), '-k',"DisplayName","rectwin","LineWidth",1.5);
            end
            if(app.OknoHannaCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.whann, 65536)/(app.N))),"DisplayName","hann","LineWidth",1.5);
            end
            if(app.OknoTrjktneCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wtriang, 65536)/(app.N))),"DisplayName","triang","LineWidth",1.5);
            end
            if(app.OknoHammingaCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.whamming, 65536)/(app.N))),"DisplayName","hamming","LineWidth",1.5);
            end
            if(app.OknoBlackmanaCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wblackman, 65536)/(app.N))),"DisplayName","blackman","LineWidth",1.5);
            end
            if(app.OknoKaiseraCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wkaiser, 65536)/(app.N))),"DisplayName","kaiser","LineWidth",1.5);
            end
            if(app.OknoGaussaCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wgausswin, 65536)/(app.N))),"DisplayName","gausswin","LineWidth",1.5);
            end
            if(app.OknoTukeyaCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wtukeywin, 65536)/(app.N))),"DisplayName","tukeywin","LineWidth",1.5);
            end
            if(app.OknoChebyshevaCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wchebwin, 65536)/(app.N))),"DisplayName","chebwin","LineWidth",1.5);
            end
            if(app.OknoBlackmanaHarrisaCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wblackmanharris, 65536)/(app.N))),"DisplayName","blackmanharris","LineWidth",1.5);
            end
            if(app.OknoNutallaCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wnuttallwin, 65536)/(app.N))),"DisplayName","nuttallwin","LineWidth",1.5);
            end
            if(app.OknoSincCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wsinc, 65536)/(app.N))),"DisplayName","sinc","LineWidth",1.5);
            end
            if(app.OknoBartlettaHannaCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wbarthannwin, 65536)/(app.N))),"DisplayName","barthannwin","LineWidth",1.5);
            end
            if(app.OknoBartlettaCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wbartlett, 65536)/(app.N))),"DisplayName","bartlett","LineWidth",1.5);
            end
            if(app.OknoBohmannaCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wbohmanwin, 65536)/(app.N))),"DisplayName","bohmanwin","LineWidth",1.5);
            end
            if(app.OknoFlattopCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wflattopwin, 65536)/(app.N))),"DisplayName","flattopwin","LineWidth",1.5);
            end
            if(app.OknoParzenaCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wparzenwin, 65536)/(app.N))),"DisplayName","parzenwin","LineWidth",1.5);
            end
            if(app.OknoTayloraCheckBox.Value)
                plot(app.UIAxes, app.n, 20*log10(abs(fft(app.wtaylorwin, 65536)/(app.N))),"DisplayName","taylorwin","LineWidth",1.5);
            end

            hold(app.UIAxes, "off");

            legend(app.UIAxes);
        end
        
        function przeliczOkna(app)
            % Metoda do ponownego obliczania próbek okien z nowym
            % parametrem podanym przez urzytkownika
            app.wkaiser = kaiser(app.N, app.pKaiser);
            app.wkaiser = normalizujOkno(app, app.wkaiser);

            app.wgausswin = gausswin(app.N, app.pGauss);
            app.wgausswin = normalizujOkno(app, app.wgausswin);

            app.wtukeywin = tukeywin(app.N, app.pTukey);
            app.wtukeywin = normalizujOkno(app, app.wtukeywin);

            app.wchebwin = chebwin(app.N, app.pCheby);
            app.wchebwin = normalizujOkno(app, app.wchebwin);

            dv = app.pSinc*2/app.N;
            vec = -1*app.pSinc:dv:1*app.pSinc;

            app.wsinc = sinc(vec).*(hann(length(vec)))';
            app.wsinc = app.wsinc';
            app.wsinc(length(app.wsinc))=[];
            app.wsinc = normalizujOkno(app, app.wsinc);

            app.wtaylorwin = taylorwin(app.N+1, app.pTaylor1, app.pTaylor2);
            app.wtaylorwin(length(app.wtaylorwin))=[];
            app.wtaylorwin = normalizujOkno(app, app.wtaylorwin);
        end
    end
        

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, N, pKaiser, pGauss, pTukey, pCheby, pTaylor1, pTaylor2, pSinc)

            % wczytanie wartości podanych z aplikacji nadrzędnej do zmiennych lokalnych aplikacji  
            app.N = N;
            app.pKaiser = pKaiser;
            app.pGauss = pGauss;
            app.pTukey = pTukey;
            app.pCheby = pCheby;
            app.pTaylor1 = pTaylor1;
            app.pTaylor2 = pTaylor2;
            app.pSinc = pSinc;
            app.pointerPos = 0;

            % Inicjalizacja wszystkich wybierajek okien na naciśnięte
            app.OknoProstoktneCheckBox.Value = true;
            app.OknoHannaCheckBox.Value = true;
            app.OknoTrjktneCheckBox.Value = true;
            app.OknoHammingaCheckBox.Value = true;
            app.OknoBlackmanaCheckBox.Value = true;
            app.OknoKaiseraCheckBox.Value = true;
            app.OknoGaussaCheckBox.Value = true;
            app.OknoTukeyaCheckBox.Value = true;
            app.OknoChebyshevaCheckBox.Value = true;
            app.OknoBlackmanaHarrisaCheckBox.Value = true;
            app.OknoNutallaCheckBox.Value = true;
            app.OknoSincCheckBox.Value = true;
            app.OknoBartlettaHannaCheckBox.Value = true;
            app.OknoBartlettaCheckBox.Value = true;
            app.OknoBohmannaCheckBox.Value = true;
            app.OknoFlattopCheckBox.Value = true;
            app.OknoParzenaCheckBox.Value = true;
            app.OknoTayloraCheckBox.Value = true;

            % Inicjalizacja okieniek pokazujących wartości parametrów
            app.KaiserEditField.Value = app.pKaiser;
            app.GaussEditField.Value = app.pGauss;
            app.TukeyEditField.Value = app.pTukey;
            app.ChebyEditField.Value = app.pCheby;
            app.Taylor1EditField.Value = app.pTaylor1;
            app.Taylor2EditField.Value = app.pTaylor2;
            app.SincEditField.Value = app.pSinc;
            
            % Inicjalizacja zakładki "Jak zacząć" w tekst z pliku txt
            txt = readlines("help_tips&tricks.txt");
            app.TextArea_2.Value = txt;

            data = readtable("Dane.xls",'PreserveVariableNames',true);
            app.UITable.Data = data;

            % Obliczenie wektora próbek dla sygnałów wypełnionych zerami
            dn = app.N/65536;
            app.n = 0:dn:app.N-dn;
            
            % Wstępne liczenie wartości wszystkich okien dla parametró
            % podanych z aplikacji nadrzędnej
            app.wrectwin = rectwin(app.N);
            app.whann = 2.*hann(app.N, "periodic");
            app.wtriang = 2.*triang(app.N+1);
            app.wtriang(length(app.wtriang))=[];
            app.wtriang = normalizujOkno(app, app.wtriang);
            app.whamming = 1.8519.*hamming(app.N, "periodic");
            app.wblackman = 2.3810.*blackman(app.N, "periodic");
            
            app.wkaiser = kaiser(app.N+1, app.pKaiser);
            app.wkaiser(length(app.wkaiser))=[];
            app.wkaiser = normalizujOkno(app, app.wkaiser);

            app.wgausswin = gausswin(app.N+1, app.pGauss);
            app.wgausswin(length(app.wgausswin))=[];
            app.wgausswin = normalizujOkno(app, app.wgausswin);

            app.wtukeywin = tukeywin(app.N+1, app.pTukey);
            app.wtukeywin(length(app.wtukeywin))=[];
            app.wtukeywin = normalizujOkno(app, app.wtukeywin);

            app.wchebwin = chebwin(app.N+1, app.pCheby);
            app.wchebwin(length(app.wchebwin))=[];
            app.wchebwin = normalizujOkno(app, app.wchebwin);

            app.wblackmanharris = blackmanharris(app.N, 'periodic');
            app.wblackmanharris = normalizujOkno(app, app.wblackmanharris);

            app.wnuttallwin = nuttallwin(app.N, 'periodic');
            app.wnuttallwin = normalizujOkno(app, app.wnuttallwin);
            
            dv = app.pSinc*2/app.N;
            vec = -1*app.pSinc:dv:1*app.pSinc;

            app.wsinc = sinc(vec).*(hann(length(vec)))';
            app.wsinc = app.wsinc';
            app.wsinc(length(app.wsinc))=[];
            app.wsinc = normalizujOkno(app, app.wsinc);

            app.wbarthannwin = barthannwin(app.N+1);
            app.wbarthannwin(length(app.wbarthannwin))=[];
            app.wbarthannwin = normalizujOkno(app, app.wbarthannwin);

            app.wbartlett = bartlett(app.N+1);
            app.wbartlett(length(app.wbartlett))=[];
            app.wbartlett = normalizujOkno(app, app.wbartlett);

            app.wbohmanwin = bohmanwin(app.N+1);
            app.wbohmanwin(length(app.wbohmanwin))=[];
            app.wbohmanwin = normalizujOkno(app, app.wbohmanwin);

            app.wflattopwin = flattopwin(app.N+1);
            app.wflattopwin(length(app.wflattopwin))=[];
            app.wflattopwin = normalizujOkno(app, app.wflattopwin);
            
            app.wparzenwin = parzenwin(app.N+1);
            app.wparzenwin(length(app.wparzenwin))=[];
            app.wparzenwin = normalizujOkno(app, app.wparzenwin);

            app.wtaylorwin = taylorwin(app.N+1, app.pTaylor1, app.pTaylor2);
            app.wtaylorwin(length(app.wtaylorwin))=[];
            app.wtaylorwin = normalizujOkno(app, app.wtaylorwin);

            % Wyświetlanie wszystkich okien na raz, opcja wyboru ukien w
            % innej metodzie
            hold(app.UIAxes, "off");
            grid(app.UIAxes, "on");
            
            % Liczę znormalizowane wartości /N a nie /(N/2) żeby wykresy
            % zaczynały się w 0dB a nie w 6dB (x2 liniowo)
            aktualizujOkno(app);

            % Ograniczenie zakresu osi żeby nie pokazywać całego wykresu
            xlim(app.UIAxes, [0,20]);
            ylim(app.UIAxes, [-120,10]);
            app.UIAxes.XMinorGrid = 'on';
            app.UIAxes.YMinorGrid = 'on';
            app.UIAxes.XMinorTick = 'on';
            app.UIAxes.YMinorTick = 'on';
%             app.UIAxes.XTick = 0:1:20;
%             app.UIAxes.YTick = -300:6:10;
            title(app.UIAxes, "Porównanie okien");
            xlabel(app.UIAxes, "Nr. próbki");
            ylabel(app.UIAxes, "Moc prążka");
            
            % Inicjalizacja odnośnika do głównej aplikacji 
            app.adg_v2 = mainapp;
        end

        % Value changed function: OknoProstoktneCheckBox
        function OknoProstoktneCheckBoxValueChanged(app, event)
            % Pamiętać o średniku na końcu, inaczej otwiera zewnetrznego
            % plota dodatkowo lol
            aktualizujOkno(app);
        end

        % Value changed function: OknoHannaCheckBox
        function OknoHannaCheckBoxValueChanged(app, event)
            aktualizujOkno(app);
        end

        % Value changed function: OknoTrjktneCheckBox
        function OknoTrjktneCheckBoxValueChanged(app, event)
            aktualizujOkno(app);
        end

        % Value changed function: OknoHammingaCheckBox
        function OknoHammingaCheckBoxValueChanged(app, event)
            aktualizujOkno(app);
        end

        % Value changed function: OknoBlackmanaCheckBox
        function OknoBlackmanaCheckBoxValueChanged(app, event)
            aktualizujOkno(app);
        end

        % Value changed function: OknoKaiseraCheckBox
        function OknoKaiseraCheckBoxValueChanged(app, event)
            aktualizujOkno(app);
        end

        % Value changed function: OknoGaussaCheckBox
        function OknoGaussaCheckBoxValueChanged(app, event)
            aktualizujOkno(app);
        end

        % Value changed function: OknoTukeyaCheckBox
        function OknoTukeyaCheckBoxValueChanged(app, event)
            aktualizujOkno(app);
        end

        % Value changed function: OknoChebyshevaCheckBox
        function OknoChebyshevaCheckBoxValueChanged(app, event)
            aktualizujOkno(app);
        end

        % Value changed function: ChebyEditField
        function ChebyEditFieldValueChanged(app, event)
            app.pCheby = app.ChebyEditField.Value;
            przeliczOkna(app);
            aktualizujOkno(app);
        end

        % Value changed function: TukeyEditField
        function TukeyEditFieldValueChanged(app, event)
            app.pTukey = app.TukeyEditField.Value;
            przeliczOkna(app);
            aktualizujOkno(app);
        end

        % Value changed function: GaussEditField
        function GaussEditFieldValueChanged(app, event)
            app.pGauss = app.GaussEditField.Value;
            przeliczOkna(app);
            aktualizujOkno(app);
        end

        % Value changed function: KaiserEditField
        function KaiserEditFieldValueChanged(app, event)
            app.pKaiser = app.KaiserEditField.Value;
            przeliczOkna(app);
            aktualizujOkno(app);
        end

        % Value changed function: OknoBlackmanaHarrisaCheckBox
        function OknoBlackmanaHarrisaCheckBoxValueChanged(app, event)
            aktualizujOkno(app);            
        end

        % Value changed function: OknoNutallaCheckBox
        function OknoNutallaCheckBoxValueChanged(app, event)
            aktualizujOkno(app);
        end

        % Value changed function: OknoSincCheckBox
        function OknoSincCheckBoxValueChanged(app, event)
            aktualizujOkno(app);            
        end

        % Value changed function: OknoBartlettaHannaCheckBox
        function OknoBartlettaHannaCheckBoxValueChanged(app, event)
            aktualizujOkno(app);            
        end

        % Value changed function: OknoBartlettaCheckBox
        function OknoBartlettaCheckBoxValueChanged(app, event)
            aktualizujOkno(app);            
        end

        % Value changed function: OknoBohmannaCheckBox
        function OknoBohmannaCheckBoxValueChanged(app, event)
            aktualizujOkno(app);            
        end

        % Value changed function: OknoFlattopCheckBox
        function OknoFlattopCheckBoxValueChanged(app, event)
            aktualizujOkno(app);            
        end

        % Value changed function: OknoParzenaCheckBox
        function OknoParzenaCheckBoxValueChanged(app, event)
            aktualizujOkno(app);            
        end

        % Value changed function: OknoTayloraCheckBox
        function OknoTayloraCheckBoxValueChanged(app, event)
            aktualizujOkno(app);   
        end

        % Value changed function: Taylor1EditField
        function Taylor1EditFieldValueChanged(app, event)
            app.pTaylor1 = app.Taylor1EditField.Value;
            przeliczOkna(app);
            aktualizujOkno(app); 
        end

        % Value changed function: Taylor2EditField
        function Taylor2EditFieldValueChanged(app, event)
            app.pTaylor2 = app.Taylor2EditField.Value;
            przeliczOkna(app);
            aktualizujOkno(app);  
        end

        % Value changed function: SincEditField
        function SincEditFieldValueChanged(app, event)
            app.pSinc = app.SincEditField.Value;
            przeliczOkna(app);
            aktualizujOkno(app);  
        end

        % Button pushed function: WczwszystkieButton
        function WczwszystkieButtonPushed(app, event)
            app.OknoProstoktneCheckBox.Value = true;
            app.OknoHannaCheckBox.Value = true;
            app.OknoTrjktneCheckBox.Value = true;
            app.OknoHammingaCheckBox.Value = true;
            app.OknoBlackmanaCheckBox.Value = true;
            app.OknoKaiseraCheckBox.Value = true;
            app.OknoGaussaCheckBox.Value = true;
            app.OknoTukeyaCheckBox.Value = true;
            app.OknoChebyshevaCheckBox.Value = true;
            app.OknoBlackmanaHarrisaCheckBox.Value = true;
            app.OknoNutallaCheckBox.Value = true;
            app.OknoSincCheckBox.Value = true;
            app.OknoBartlettaHannaCheckBox.Value = true;
            app.OknoBartlettaCheckBox.Value = true;
            app.OknoBohmannaCheckBox.Value = true;
            app.OknoFlattopCheckBox.Value = true;
            app.OknoParzenaCheckBox.Value = true;
            app.OknoTayloraCheckBox.Value = true;

            aktualizujOkno(app);
        end

        % Button pushed function: WyczwszystkieButton
        function WyczwszystkieButtonPushed(app, event)
            app.OknoProstoktneCheckBox.Value = false;
            app.OknoHannaCheckBox.Value = false;
            app.OknoTrjktneCheckBox.Value = false;
            app.OknoHammingaCheckBox.Value = false;
            app.OknoBlackmanaCheckBox.Value = false;
            app.OknoKaiseraCheckBox.Value = false;
            app.OknoGaussaCheckBox.Value = false;
            app.OknoTukeyaCheckBox.Value = false;
            app.OknoChebyshevaCheckBox.Value = false;
            app.OknoBlackmanaHarrisaCheckBox.Value = false;
            app.OknoNutallaCheckBox.Value = false;
            app.OknoSincCheckBox.Value = false;
            app.OknoBartlettaHannaCheckBox.Value = false;
            app.OknoBartlettaCheckBox.Value = false;
            app.OknoBohmannaCheckBox.Value = false;
            app.OknoFlattopCheckBox.Value = false;
            app.OknoParzenaCheckBox.Value = false;
            app.OknoTayloraCheckBox.Value = false;

            aktualizujOkno(app);
        end

        % Value changed function: PozycjawskanikaEditField
        function PozycjawskanikaEditFieldValueChanged(app, event)
            app.pointerPos = app.PozycjawskanikaEditField.Value;
            aktualizujOkno(app); 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.PorwnanieokienTab = uitab(app.TabGroup);
            app.PorwnanieokienTab.Title = 'Porównanie okien';
            app.GridLayout3 = uigridlayout(app.PorwnanieokienTab);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ParametryokienparametrycznychPanel = uipanel(app.GridLayout3);
            app.ParametryokienparametrycznychPanel.Title = 'Parametry okien parametrycznych';
            app.GridLayout4 = uigridlayout(app.ParametryokienparametrycznychPanel);
            app.KaiserEditFieldLabel = uilabel(app.GridLayout4);
            app.KaiserEditFieldLabel.Text = 'Kaiser:';
            app.KaiserEditField = uieditfield(app.GridLayout4, 'numeric');
            app.KaiserEditField.ValueChangedFcn = createCallbackFcn(app, @KaiserEditFieldValueChanged, true);
            app.KaiserEditField.Value = 0.5;
            app.GaussEditFieldLabel = uilabel(app.GridLayout4);
            app.GaussEditFieldLabel.Text = 'Gauss:';
            app.GaussEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GaussEditField.ValueChangedFcn = createCallbackFcn(app, @GaussEditFieldValueChanged, true);
            app.GaussEditField.Value = 2.5;
            app.TukeyEditFieldLabel = uilabel(app.GridLayout4);
            app.TukeyEditFieldLabel.Text = 'Tukey:';
            app.TukeyEditField = uieditfield(app.GridLayout4, 'numeric');
            app.TukeyEditField.ValueChangedFcn = createCallbackFcn(app, @TukeyEditFieldValueChanged, true);
            app.TukeyEditField.Value = 0.5;
            app.ChebyEditFieldLabel = uilabel(app.GridLayout4);
            app.ChebyEditFieldLabel.Text = 'Cheby:';
            app.ChebyEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ChebyEditField.ValueChangedFcn = createCallbackFcn(app, @ChebyEditFieldValueChanged, true);
            app.ChebyEditField.Value = 100;
            app.Taylor1EditFieldLabel = uilabel(app.GridLayout4);
            app.Taylor1EditFieldLabel.Text = 'Taylor 1:';
            app.Taylor1EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Taylor1EditField.ValueChangedFcn = createCallbackFcn(app, @Taylor1EditFieldValueChanged, true);
            app.Taylor2EditFieldLabel = uilabel(app.GridLayout4);
            app.Taylor2EditFieldLabel.Text = 'Taylor 2:';
            app.Taylor2EditField = uieditfield(app.GridLayout4, 'numeric');
            app.Taylor2EditField.ValueChangedFcn = createCallbackFcn(app, @Taylor2EditFieldValueChanged, true);
            app.SincEditFieldLabel = uilabel(app.GridLayout4);
            app.SincEditFieldLabel.Text = 'Sinc:';
            app.SincEditField = uieditfield(app.GridLayout4, 'numeric');
            app.SincEditField.ValueChangedFcn = createCallbackFcn(app, @SincEditFieldValueChanged, true);
            app.WczwszystkieButton = uibutton(app.GridLayout3, 'push');
            app.WczwszystkieButton.ButtonPushedFcn = createCallbackFcn(app, @WczwszystkieButtonPushed, true);
            app.WczwszystkieButton.Text = 'Włącz wszystkie';
            app.WyczwszystkieButton = uibutton(app.GridLayout3, 'push');
            app.WyczwszystkieButton.ButtonPushedFcn = createCallbackFcn(app, @WyczwszystkieButtonPushed, true);
            app.WyczwszystkieButton.Text = 'Wyłącz wszystkie';
            app.PorwnajOknaPanel = uipanel(app.GridLayout3);
            app.PorwnajOknaPanel.Title = 'Porównaj Okna:';
            app.GridLayout5 = uigridlayout(app.PorwnajOknaPanel);
            app.OknoHannaCheckBox = uicheckbox(app.GridLayout5);
            app.OknoHannaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoHannaCheckBoxValueChanged, true);
            app.OknoHannaCheckBox.Text = 'Okno Hanna';
            app.OknoHannaCheckBox.Value = true;
            app.OknoTrjktneCheckBox = uicheckbox(app.GridLayout5);
            app.OknoTrjktneCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoTrjktneCheckBoxValueChanged, true);
            app.OknoTrjktneCheckBox.Text = 'Okno Trójkątne';
            app.OknoTrjktneCheckBox.Value = true;
            app.OknoHammingaCheckBox = uicheckbox(app.GridLayout5);
            app.OknoHammingaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoHammingaCheckBoxValueChanged, true);
            app.OknoHammingaCheckBox.Text = 'Okno Hamminga';
            app.OknoHammingaCheckBox.Value = true;
            app.OknoBlackmanaCheckBox = uicheckbox(app.GridLayout5);
            app.OknoBlackmanaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoBlackmanaCheckBoxValueChanged, true);
            app.OknoBlackmanaCheckBox.Text = 'Okno Blackmana';
            app.OknoBlackmanaCheckBox.Value = true;
            app.OknoKaiseraCheckBox = uicheckbox(app.GridLayout5);
            app.OknoKaiseraCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoKaiseraCheckBoxValueChanged, true);
            app.OknoKaiseraCheckBox.Text = 'Okno Kaisera';
            app.OknoKaiseraCheckBox.Value = true;
            app.OknoGaussaCheckBox = uicheckbox(app.GridLayout5);
            app.OknoGaussaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoGaussaCheckBoxValueChanged, true);
            app.OknoGaussaCheckBox.Text = 'Okno Gaussa';
            app.OknoGaussaCheckBox.Value = true;
            app.OknoTukeyaCheckBox = uicheckbox(app.GridLayout5);
            app.OknoTukeyaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoTukeyaCheckBoxValueChanged, true);
            app.OknoTukeyaCheckBox.Text = 'Okno Tukeya';
            app.OknoTukeyaCheckBox.Value = true;
            app.OknoChebyshevaCheckBox = uicheckbox(app.GridLayout5);
            app.OknoChebyshevaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoChebyshevaCheckBoxValueChanged, true);
            app.OknoChebyshevaCheckBox.Text = 'Okno Chebysheva';
            app.OknoChebyshevaCheckBox.Value = true;
            app.OknoProstoktneCheckBox = uicheckbox(app.GridLayout5);
            app.OknoProstoktneCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoProstoktneCheckBoxValueChanged, true);
            app.OknoProstoktneCheckBox.Text = 'Okno Prostokątne';
            app.OknoProstoktneCheckBox.Value = true;
            app.OknoBlackmanaHarrisaCheckBox = uicheckbox(app.GridLayout5);
            app.OknoBlackmanaHarrisaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoBlackmanaHarrisaCheckBoxValueChanged, true);
            app.OknoBlackmanaHarrisaCheckBox.Text = 'Okno Blackmana-Harrisa';
            app.OknoBlackmanaHarrisaCheckBox.Value = true;
            app.OknoNutallaCheckBox = uicheckbox(app.GridLayout5);
            app.OknoNutallaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoNutallaCheckBoxValueChanged, true);
            app.OknoNutallaCheckBox.Text = 'Okno Nutalla';
            app.OknoNutallaCheckBox.Value = true;
            app.OknoSincCheckBox = uicheckbox(app.GridLayout5);
            app.OknoSincCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoSincCheckBoxValueChanged, true);
            app.OknoSincCheckBox.Text = 'Okno Sinc';
            app.OknoSincCheckBox.Value = true;
            app.OknoBartlettaHannaCheckBox = uicheckbox(app.GridLayout5);
            app.OknoBartlettaHannaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoBartlettaHannaCheckBoxValueChanged, true);
            app.OknoBartlettaHannaCheckBox.Text = 'Okno Bartletta-Hanna';
            app.OknoBartlettaHannaCheckBox.Value = true;
            app.OknoBartlettaCheckBox = uicheckbox(app.GridLayout5);
            app.OknoBartlettaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoBartlettaCheckBoxValueChanged, true);
            app.OknoBartlettaCheckBox.Text = 'Okno Bartletta';
            app.OknoBartlettaCheckBox.Value = true;
            app.OknoBohmannaCheckBox = uicheckbox(app.GridLayout5);
            app.OknoBohmannaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoBohmannaCheckBoxValueChanged, true);
            app.OknoBohmannaCheckBox.Text = 'Okno Bohmanna';
            app.OknoBohmannaCheckBox.Value = true;
            app.OknoFlattopCheckBox = uicheckbox(app.GridLayout5);
            app.OknoFlattopCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoFlattopCheckBoxValueChanged, true);
            app.OknoFlattopCheckBox.Text = 'Okno Flattop';
            app.OknoFlattopCheckBox.Value = true;
            app.OknoParzenaCheckBox = uicheckbox(app.GridLayout5);
            app.OknoParzenaCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoParzenaCheckBoxValueChanged, true);
            app.OknoParzenaCheckBox.Text = 'Okno Parzena';
            app.OknoParzenaCheckBox.Value = true;
            app.OknoTayloraCheckBox = uicheckbox(app.GridLayout5);
            app.OknoTayloraCheckBox.ValueChangedFcn = createCallbackFcn(app, @OknoTayloraCheckBoxValueChanged, true);
            app.OknoTayloraCheckBox.Text = 'Okno Taylora';
            app.OknoTayloraCheckBox.Value = true;
            app.PozycjawskanikaEditFieldLabel = uilabel(app.GridLayout3);
            app.PozycjawskanikaEditFieldLabel.Text = 'Pozycja wskaźnika:';
            app.PozycjawskanikaEditField = uieditfield(app.GridLayout3, 'numeric');
            app.PozycjawskanikaEditField.ValueChangedFcn = createCallbackFcn(app, @PozycjawskanikaEditFieldValueChanged, true);
            app.Tabelaparametrwokien = uitab(app.TabGroup);
            app.Tabelaparametrwokien.Title = 'Tabela parametrów okien';
            app.GridLayout7 = uigridlayout(app.Tabelaparametrwokien);
            app.UITable = uitable(app.GridLayout7);
            app.UITable.ColumnName = {'Okno'; 'Amplituda listka bocznego [dB]'; 'Spadek listków bocznych [dB/oct]'; 'Poziom wycieku widma [%]'; 'Ekwiwalent szumu [Bins]'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true true true true];
            app.WskazwkidoboruokienTab = uitab(app.TabGroup);
            app.WskazwkidoboruokienTab.Title = 'Wskazówki doboru okien';
            app.GridLayout6 = uigridlayout(app.WskazwkidoboruokienTab);
            app.TextArea_2 = uitextarea(app.GridLayout6);
            app.TextArea_2.Placeholder = 'https://youtu.be/dQw4w9WgXcQ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winHelpWin(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end