classdef adg < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DomyslneButton          matlab.ui.control.Button
        ParametrEditField       matlab.ui.control.NumericEditField
        ParametrEditFieldLabel  matlab.ui.control.Label
        ListBoxWykres           matlab.ui.control.ListBox
        ListBoxOkno             matlab.ui.control.ListBox
        CzytajprobkiButton      matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        N               % Liczba probek
        n               % indeks probek
        x               % wektor probek sygnalu
        w               % wektor probek okna czasowego
        Nrokna          % numer wybranego okna czasowego
        Nrwykresu       % numer wybranego wykresu

        para            %parametr wybranego okna
    end
    
    methods (Access = private)
        
        function RysujWykres1(app)
            hold(app.UIAxes, "off");
            set(app.UIAxes,'YLimMode',"auto");
            plot(app.UIAxes, app.n,app.x,'b.');
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.n,app.x,'b');
            plot(app.UIAxes, app.n,app.w,'g.');
            plot(app.UIAxes, app.n,app.w,'g');
            plot(app.UIAxes, app.n,app.w.*app.x,'r.');
            plot(app.UIAxes, app.n,app.w.*app.x,'r');
        end
        
        function RysujWykres2(app)
            f=fft(app.x.*app.w);
            modf=abs(f)/(app.N/2);
            hold(app.UIAxes, "off");
            plot(app.UIAxes, app.n,modf,'g.');
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.n,modf,'g');
            axis(app.UIAxes,[0 app.N-1 0 1]);
        end

        function RysujWykres3(app)
            f=fft(app.x.*app.w);
            modf=abs(f)/(app.N/2);
            hold(app.UIAxes, "off");
            plot(app.UIAxes, app.n,20*log10(modf),'g.');
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.n,20*log10(modf),'g');
            axis(app.UIAxes,[0 app.N-1 -100 0]);
        end

        function RysujWykres4(app)
            f=fft(app.x.*app.w);
            modf=abs(f)/(app.N/2);
            
            alfa = angle(f);
            idx = modf<0.00001;
            alfa(idx) = NaN;

            hold(app.UIAxes, "off");
            plot(app.UIAxes, app.n, alfa ,'g.');
            hold(app.UIAxes, "on");
            plot(app.UIAxes, app.n, alfa,'g');
            axis(app.UIAxes,[0 app.N-1 -5 5]);
        end
        
        function RysujWykres(app)
            switch(app.Nrwykresu)
                case 1
                    RysujWykres1(app);
                case 2
                    RysujWykres2(app);
                case 3
                    RysujWykres3(app);
                case 4
                    RysujWykres4(app);
            end
        end
        
        function ObliczOkno(app)
            switch(app.Nrokna)
                case 1
                    app.ParametrEditField.Enable = "off";
                    app.DomyslneButton.Enable = "off";
                    app.ParametrEditFieldLabel.Text = "No param";

                    app.w = rectwin(app.N);
                    app.w = app.w';
                case 2
                    app.ParametrEditField.Enable = "off";
                    app.DomyslneButton.Enable = "off";
                    app.ParametrEditFieldLabel.Text = "No param";

                    %app.w = 1-cos(2*pi*app.n/app.N);
                    app.w = 2.*hann(app.N, "periodic");
                    app.w = app.w';
                case 3
                    app.ParametrEditField.Enable = "off";
                    app.DomyslneButton.Enable = "off";
                    app.ParametrEditFieldLabel.Text = "No param";

                    app.w = 2.*triang(app.N);
                    app.w = app.w';
                case 4 
                    app.ParametrEditField.Enable = "off";
                    app.DomyslneButton.Enable = "off";
                    app.ParametrEditFieldLabel.Text = "No param";

                    app.w = 1.8519.*hamming(app.N, "periodic");
                    app.w = app.w';
                case 5
                    app.ParametrEditField.Enable = "off";
                    app.DomyslneButton.Enable = "off";
                    app.ParametrEditFieldLabel.Text = "No param";

                    app.w = 2.3810.*blackman(app.N, "periodic");
                    app.w = app.w';
                case 6
                    app.ParametrEditField.Enable = "on";
                    app.DomyslneButton.Enable = "on";
                    app.ParametrEditFieldLabel.Text = "Beta: ";

                    app.w = kaiser(app.N, app.para);
                    normalizujOkno(app);
                case 7
                    app.ParametrEditField.Enable = "on";
                    app.DomyslneButton.Enable = "on";
                    app.ParametrEditFieldLabel.Text = "Alfa: ";

                    app.w = gausswin(app.N, app.para);
                    normalizujOkno(app);
                case 8
                    app.ParametrEditField.Enable = "on";
                    app.DomyslneButton.Enable = "on";
                    app.ParametrEditFieldLabel.Text = "Ułamek Cos: ";
                    
                    app.w = tukeywin(app.N, app.para);
                    normalizujOkno(app);
                case 9
                    app.ParametrEditField.Enable = "on";
                    app.DomyslneButton.Enable = "on";
                    app.ParametrEditFieldLabel.Text = "Tłumienie: ";
                    
                    app.w = chebwin(app.N, app.para);
                    normalizujOkno(app);
            end
        end


        function normalizujOkno(app)
            app.w = app.w';
            sum = 0;
            for i = 1:app.N
                sum = sum + app.w(i);
            end
            
            multiplier = 128/sum;
            app.w = multiplier.*app.w;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ad_init(app)
            app.N=128;
            app.n=0:app.N-1;
            app.x=zeros(1,app.N);
            app.w=ones(1,app.N);
            app.Nrokna=1;
            app.Nrwykresu=1;
            RysujWykres1(app);

            app.ParametrEditField.Enable = "off";
            app.DomyslneButton.Enable = "off";
            app.para = 0;
        end

        % Button pushed function: CzytajprobkiButton
        function ad_czytaj_probki(app, event)
            [st1,st2]=uigetfile('*.mat','Czytaj dane');
            if st1~=0
                eval(['load ''' [st2,st1] ''' -mat x']);
                app.x=x;
            end
            RysujWykres(app);
        end

        % Value changed function: ListBoxOkno
        function ad_wybor_okna(app, event)
            app.Nrokna = app.ListBoxOkno.Value;
            ObliczOkno(app);
            RysujWykres(app);

        end

        % Value changed function: ListBoxWykres
        function ad_wybor_wykresu(app, event)
            app.Nrwykresu = app.ListBoxWykres.Value;
            RysujWykres(app);
        end

        % Value changed function: ParametrEditField
        function ad_wpisz_parametr(app, event)
            app.para = app.ParametrEditField.Value;
            ObliczOkno(app);
            RysujWykres(app);
        end

        % Button pushed function: DomyslneButton
        function DomyslneButtonPushed(app, event)
            switch(app.Nrokna)
                case 6
                    app.ParametrEditField.Value = 0.5;
                case 7
                    app.ParametrEditField.Value = 2.5;
                case 8
                    app.ParametrEditField.Value = 0.5;
                case 9
                    app.ParametrEditField.Value = 100;
            end
            ad_wpisz_parametr(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.CzytajprobkiButton = uibutton(app.UIFigure, 'push');
            app.CzytajprobkiButton.ButtonPushedFcn = createCallbackFcn(app, @ad_czytaj_probki, true);
            app.CzytajprobkiButton.Text = 'Czytaj probki';
            app.ListBoxOkno = uilistbox(app.UIFigure);
            app.ListBoxOkno.Items = {'Okno prostokątne', 'Okon Hanna', 'Okon trójkątne', 'Okno Hamminga', 'Okno Blackmana', 'Okno Kaisera', 'Okno Gaussa', 'Okno Tukeya', 'Okno Chebysheva'};
            app.ListBoxOkno.ItemsData = [1 2 3 4 5 6 7 8 9];
            app.ListBoxOkno.ValueChangedFcn = createCallbackFcn(app, @ad_wybor_okna, true);
            app.ListBoxOkno.Value = 1;
            app.ListBoxWykres = uilistbox(app.UIFigure);
            app.ListBoxWykres.Items = {'Wykres w dziedzinie czasu', 'Ch-ka amplitudowa', 'Ch-ka amplitudowa w dB', 'Ch-ka fazowa'};
            app.ListBoxWykres.ItemsData = [1 2 3 4];
            app.ListBoxWykres.ValueChangedFcn = createCallbackFcn(app, @ad_wybor_wykresu, true);
            app.ListBoxWykres.Value = 1;
            app.ParametrEditFieldLabel = uilabel(app.UIFigure);
            app.ParametrEditFieldLabel.Text = 'Parametr:';
            app.ParametrEditField = uieditfield(app.UIFigure, 'numeric');
            app.ParametrEditField.ValueChangedFcn = createCallbackFcn(app, @ad_wpisz_parametr, true);
            app.DomyslneButton = uibutton(app.UIFigure, 'push');
            app.DomyslneButton.ButtonPushedFcn = createCallbackFcn(app, @DomyslneButtonPushed, true);
            app.DomyslneButton.Text = 'Domyslne';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = adg

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @ad_init)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end