classdef kalkulator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        SkadowastaadB2Panel            matlab.ui.container.Panel
        GridLayout5                    matlab.ui.container.GridLayout
        SkladowaDB                     matlab.ui.control.NumericEditField
        SlkadowaLin2                   matlab.ui.control.NumericEditField
        dBstosunekPanel                matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        ValRatio4                      matlab.ui.control.NumericEditField
        ValDB4                         matlab.ui.control.NumericEditField
        ValRatio3                      matlab.ui.control.NumericEditField
        ValDB3                         matlab.ui.control.NumericEditField
        ValRatio2                      matlab.ui.control.NumericEditField
        ValDB2                         matlab.ui.control.NumericEditField
        ValRatio1                      matlab.ui.control.NumericEditField
        ValDB1                         matlab.ui.control.NumericEditField
        BinsCzstotliwoPanel            matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        WartociPanel                   matlab.ui.container.Panel
        GridLayout4                    matlab.ui.container.GridLayout
        EditFieldFreq4                 matlab.ui.control.NumericEditField
        EditFieldBin4                  matlab.ui.control.NumericEditField
        EditFieldFreq3                 matlab.ui.control.NumericEditField
        EditFieldBin3                  matlab.ui.control.NumericEditField
        EditFieldFreq2                 matlab.ui.control.NumericEditField
        EditFieldBin2                  matlab.ui.control.NumericEditField
        EditFieldFreq1                 matlab.ui.control.NumericEditField
        EditFieldBin1                  matlab.ui.control.NumericEditField
        CzstotliwoprbkowaniaEditField  matlab.ui.control.NumericEditField
        CzstotliwoprbkowaniaLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        adg_v2 
        N
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, N)
            app.N = N;

            app.adg_v2 = mainapp;
        end

        % Value changed function: EditFieldBin1
        function EditFieldBin1ValueChanged(app, event)
            app.EditFieldFreq1.Value = app.EditFieldBin1.Value * app.CzstotliwoprbkowaniaEditField.Value/app.N;

        end

        % Value changed function: EditFieldBin2
        function EditFieldBin2ValueChanged(app, event)
            app.EditFieldFreq2.Value = app.EditFieldBin2.Value * app.CzstotliwoprbkowaniaEditField.Value/app.N;
            
        end

        % Value changed function: EditFieldBin3
        function EditFieldBin3ValueChanged(app, event)
            app.EditFieldFreq3.Value = app.EditFieldBin3.Value * app.CzstotliwoprbkowaniaEditField.Value/app.N;
            
        end

        % Value changed function: EditFieldBin4
        function EditFieldBin4ValueChanged(app, event)
            app.EditFieldFreq4.Value = app.EditFieldBin4.Value * app.CzstotliwoprbkowaniaEditField.Value/app.N;
            
        end

        % Value changed function: SkladowaDB
        function SkladowaDBValueChanged(app, event)
            app.SlkadowaLin2.Value = 20*log10((10^(app.SkladowaDB.Value/20))/2);
            
        end

        % Value changed function: ValDB1
        function ValDB1ValueChanged(app, event)
            app.ValRatio1.Value = 10^(app.ValDB1.Value/20);
            
        end

        % Value changed function: ValDB2
        function ValDB2ValueChanged(app, event)
            app.ValRatio2.Value = 10^(app.ValDB2.Value/20);
            
        end

        % Value changed function: ValDB3
        function ValDB3ValueChanged(app, event)
            app.ValRatio3.Value = 10^(app.ValDB3.Value/20);
            
        end

        % Value changed function: ValDB4
        function ValDB4ValueChanged(app, event)
            app.ValRatio4.Value = 10^(app.ValDB4.Value/20);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.BinsCzstotliwoPanel = uipanel(app.GridLayout);
            app.BinsCzstotliwoPanel.Title = 'Bins -> Częstotliwość';
            app.GridLayout2 = uigridlayout(app.BinsCzstotliwoPanel);
            app.CzstotliwoprbkowaniaLabel = uilabel(app.GridLayout2);
            app.CzstotliwoprbkowaniaLabel.Text = {'Częstotliwość '; 'próbkowania:'};
            app.CzstotliwoprbkowaniaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.WartociPanel = uipanel(app.GridLayout2);
            app.WartociPanel.Title = 'Wartości';
            app.GridLayout4 = uigridlayout(app.WartociPanel);
            app.EditFieldBin1 = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldBin1.ValueChangedFcn = createCallbackFcn(app, @EditFieldBin1ValueChanged, true);
            app.EditFieldFreq1 = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldFreq1.Editable = 'off';
            app.EditFieldBin2 = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldBin2.ValueChangedFcn = createCallbackFcn(app, @EditFieldBin2ValueChanged, true);
            app.EditFieldFreq2 = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldFreq2.Editable = 'off';
            app.EditFieldBin3 = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldBin3.ValueChangedFcn = createCallbackFcn(app, @EditFieldBin3ValueChanged, true);
            app.EditFieldFreq3 = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldFreq3.Editable = 'off';
            app.EditFieldBin4 = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldBin4.ValueChangedFcn = createCallbackFcn(app, @EditFieldBin4ValueChanged, true);
            app.EditFieldFreq4 = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldFreq4.Editable = 'off';
            app.dBstosunekPanel = uipanel(app.GridLayout);
            app.dBstosunekPanel.Title = 'dB -> stosunek';
            app.GridLayout3 = uigridlayout(app.dBstosunekPanel);
            app.ValDB1 = uieditfield(app.GridLayout3, 'numeric');
            app.ValDB1.ValueChangedFcn = createCallbackFcn(app, @ValDB1ValueChanged, true);
            app.ValRatio1 = uieditfield(app.GridLayout3, 'numeric');
            app.ValRatio1.Editable = 'off';
            app.ValDB2 = uieditfield(app.GridLayout3, 'numeric');
            app.ValDB2.ValueChangedFcn = createCallbackFcn(app, @ValDB2ValueChanged, true);
            app.ValRatio2 = uieditfield(app.GridLayout3, 'numeric');
            app.ValRatio2.Editable = 'off';
            app.ValDB3 = uieditfield(app.GridLayout3, 'numeric');
            app.ValDB3.ValueChangedFcn = createCallbackFcn(app, @ValDB3ValueChanged, true);
            app.ValRatio3 = uieditfield(app.GridLayout3, 'numeric');
            app.ValRatio3.Editable = 'off';
            app.ValDB4 = uieditfield(app.GridLayout3, 'numeric');
            app.ValDB4.ValueChangedFcn = createCallbackFcn(app, @ValDB4ValueChanged, true);
            app.ValRatio4 = uieditfield(app.GridLayout3, 'numeric');
            app.ValRatio4.Editable = 'off';
            app.SkadowastaadB2Panel = uipanel(app.GridLayout);
            app.SkadowastaadB2Panel.Title = 'Składowa stała dB/2';
            app.GridLayout5 = uigridlayout(app.SkadowastaadB2Panel);
            app.SlkadowaLin2 = uieditfield(app.GridLayout5, 'numeric');
            app.SlkadowaLin2.Editable = 'off';
            app.SkladowaDB = uieditfield(app.GridLayout5, 'numeric');
            app.SkladowaDB.ValueChangedFcn = createCallbackFcn(app, @SkladowaDBValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kalkulator(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end