classdef lab5demo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        SkalaButtonGroup          matlab.ui.container.ButtonGroup
        dBButton                  matlab.ui.control.ToggleButton
        linButton                 matlab.ui.control.ToggleButton
        EditField                 matlab.ui.control.NumericEditField
        Deltalambda_0Slider       matlab.ui.control.Slider
        Deltalambda_0SliderLabel  matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        dk % czest
        isLog % Description
    end
    
    methods (Access = private)
        
        function refresh(app)
            N=16;n=0:N-1; M=32;
            m = (0:M*N-1)/M;
            sig = exp(1j*2*pi*(N/4+app.dk)*n/N);
            if(~app.isLog)
                plot(app.UIAxes, m, abs(fft(sig,N*M)/N));
                hold(app.UIAxes, "on");
                stem(app.UIAxes, n, abs(fft(sig)/N),'r');
                xlim(app.UIAxes, [N/4-2,N/4+2]);
                ylim(app.UIAxes, [0,1]);
                hold(app.UIAxes, "off");
            else
                plot(app.UIAxes, m, 20*log10(abs(fft(sig,N*M)/N)));
                hold(app.UIAxes, "on");
                plot(app.UIAxes, n, 20*log10(abs(fft(sig)/N)),'ro');
                xlim(app.UIAxes, [N/4-2,N/4+2]);
                ylim(app.UIAxes, [-30,0]);
                hold(app.UIAxes, "off");
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dk=0;
            app.isLog = false;

            app.refresh();
        end

        % Value changing function: Deltalambda_0Slider
        function Deltalambda_0SliderValueChanging(app, event)
            changingValue = event.Value;
            app.EditField.Value = changingValue;
            app.dk = changingValue;


            app.refresh();
        end

        % Selection changed function: SkalaButtonGroup
        function SkalaButtonGroupSelectionChanged(app, event)
            app.isLog = ~app.isLog; 
            app.refresh();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'bin')
            zlabel(app.UIAxes, 'Z')
            app.Deltalambda_0SliderLabel = uilabel(app.UIFigure);
            app.Deltalambda_0SliderLabel.Text = '$\Delta \lambda_0$';
            app.Deltalambda_0Slider = uislider(app.UIFigure);
            app.Deltalambda_0Slider.Limits = [-0.5 0.5];
            app.Deltalambda_0Slider.ValueChangingFcn = createCallbackFcn(app, @Deltalambda_0SliderValueChanging, true);
            app.EditField = uieditfield(app.UIFigure, 'numeric');
            app.EditField.ValueDisplayFormat = '%11.2g';
            app.EditField.Editable = 'off';
            app.SkalaButtonGroup = uibuttongroup(app.UIFigure);
            app.SkalaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SkalaButtonGroupSelectionChanged, true);
            app.SkalaButtonGroup.Title = 'Skala';
            app.linButton = uitogglebutton(app.SkalaButtonGroup);
            app.linButton.Text = 'lin';
            app.linButton.Value = true;
            app.dBButton = uitogglebutton(app.SkalaButtonGroup);
            app.dBButton.Text = 'dB';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab5demo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end